classdef ELC2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        CloseAll      matlab.ui.control.Button
        ClearAll      matlab.ui.control.Button
        uipanel19     matlab.ui.container.Panel
        checkbox2     matlab.ui.control.CheckBox
        checkbox6     matlab.ui.control.CheckBox
        checkbox7     matlab.ui.control.CheckBox
        uipanel11     matlab.ui.container.Panel
        checkbox35    matlab.ui.control.CheckBox
        text129       matlab.ui.control.Label
        edit100       matlab.ui.control.EditField
        edit93        matlab.ui.control.EditField
        text122       matlab.ui.control.Label
        text121       matlab.ui.control.Label
        edit92        matlab.ui.control.EditField
        D_Reduct      matlab.ui.control.EditField
        text72        matlab.ui.control.Label
        text71        matlab.ui.control.Label
        N_CWT         matlab.ui.control.EditField
        uipanel20     matlab.ui.container.Panel
        pushbutton44  matlab.ui.control.Button
        checkbox11    matlab.ui.control.CheckBox
        text80        matlab.ui.control.Label
        Sigma3        matlab.ui.control.EditField
        Status2       matlab.ui.control.Button
        S_PCA         matlab.ui.control.Button
        CWT           matlab.ui.control.Button
        text66        matlab.ui.control.Label
        Scales        matlab.ui.control.EditField
        text65        matlab.ui.control.Label
        Angles        matlab.ui.control.EditField
        text64        matlab.ui.control.Label
        N_Angles      matlab.ui.control.EditField
        text63        matlab.ui.control.Label
        Scale_D       matlab.ui.control.EditField
        text62        matlab.ui.control.Label
        N_Scales      matlab.ui.control.EditField
        uipanel14     matlab.ui.container.Panel
        pushbutton38  matlab.ui.control.Button
        pushbutton37  matlab.ui.control.Button
        text132       matlab.ui.control.Label
        edit103       matlab.ui.control.EditField
        text117       matlab.ui.control.Label
        edit89        matlab.ui.control.EditField
        text102       matlab.ui.control.Label
        text101       matlab.ui.control.Label
        text100       matlab.ui.control.Label
        text99        matlab.ui.control.Label
        text98        matlab.ui.control.Label
        edit73        matlab.ui.control.EditField
        edit72        matlab.ui.control.EditField
        popupmenu13   matlab.ui.control.DropDown
        uipanel10     matlab.ui.container.Panel
        text25        matlab.ui.control.Label
        Sigma2        matlab.ui.control.EditField
        popupmenu5    matlab.ui.control.DropDown
        Ymax          matlab.ui.control.EditField
        Ymin          matlab.ui.control.EditField
        Xmax          matlab.ui.control.EditField
        Xmin          matlab.ui.control.EditField
        text44        matlab.ui.control.Label
        text43        matlab.ui.control.Label
        text42        matlab.ui.control.Label
        text41        matlab.ui.control.Label
        uipanel1      matlab.ui.container.Panel
        CoordsReads   matlab.ui.control.DropDown
        uipanel2      matlab.ui.container.Panel
        text24        matlab.ui.control.Label
        text19        matlab.ui.control.Label
        Sigma1        matlab.ui.control.EditField
        XSpacing      matlab.ui.control.EditField
        uipanel5      matlab.ui.container.Panel
        text39        matlab.ui.control.Label
        Parameter1    matlab.ui.control.EditField
        X_Pix         matlab.ui.control.Button
        Y_Pix         matlab.ui.control.Button
        PointData     matlab.ui.control.DropDown
        text79        matlab.ui.control.Label
        text78        matlab.ui.control.Label
        text76        matlab.ui.control.Label
        uipanel19_2   matlab.ui.container.Panel
        edit102       matlab.ui.control.EditField
        text131       matlab.ui.control.Label
        pushbutton46  matlab.ui.control.Button
        edit101       matlab.ui.control.EditField
        text130       matlab.ui.control.Label
        edit99        matlab.ui.control.EditField
        text128       matlab.ui.control.Label
        edit98        matlab.ui.control.EditField
        text127       matlab.ui.control.Label
        Lineaments    matlab.ui.control.Button
        edit91        matlab.ui.control.EditField
        text119       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CLE2D_OpeningFcn(app, varargin)
            % This function initializes the CLE2D app upon opening.
               persistent IsBusy
    if isempty(IsBusy)
        IsBusy = false;
    end

    if IsBusy
        return;
    end

    IsBusy = true;
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            handles.output = hObject;
            guidata(hObject, handles);

    % Reset the IsBusy state
    IsBusy = false;

        end

        % Button pushed function: CWT
        function CWT_Callback(app, event)
        % This function handles the Continuous Wavelet Transform (CWT) callback.

        % Persistent variable to ensure the function does not run multiple times simultaneously
    %              persistent IsBusy
    % if isempty(IsBusy)
    %     IsBusy = false;
    % end
    % 
    % if IsBusy
    %     return;
    % end
    % 
    % IsBusy = true;
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            

            % Display a waitbar indicating the process has started
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Continuous Wavelet Transform...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            

             % Declare global variables used in this function

            global CWT_Input_Features_Matrix
            global XI
            global YI
            global Scales
            global Angles
            global N_Scales
            global orderx
            global ordery
            global Sigma3
            global N_Fs
            global N_Angles
            global pop_choice2
            pop_choice2
            
            % Read values from the GUI components

            Sigma3 = str2num(get(handles.Sigma3,'String'));
            Scales = str2num(get(handles.Scales,'String'))
            Angles_Degree = str2num(get(handles.Angles,'String'))
            Angles =  Angles_Degree*pi / (180)
            N_Scales = str2num(get(handles.N_Scales,'String'));
            N_Angles = str2num(get(handles.N_Angles,'String'));
            N_CWT = str2num(get(handles.N_CWT,'String'));
            orderx = str2num(get(handles.edit92,'String'));
            ordery = str2num(get(handles.edit93,'String'));

            % Read checkbox value
            valu_auto = get(handles.checkbox35, 'Value');
            
            % Calculate the number of features
            N_Fs = N_CWT/ (N_Angles * N_Scales)
            
            % Declare global variable for the features        
            global Fe_All
            

            % Perform CWT based on the selected method
            
            [Fe_All,Fe] = cwt2D_DerGus(CWT_Input_Features_Matrix,Scales,Angles,orderx,ordery,Sigma3,valu_auto); % ,Scales,Angles,orderx,ordery
                        
            
             % Close the waitbar
            close(h)
            pause(0.01);

            
             % Display a waitbar indicating saving results
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Save Results...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
    % Allocate the feature matrix
            for fss = 1:N_Fs
                Fs(:,((fss-1)*(N_Scales*N_Angles)+1):fss*(N_Scales*N_Angles)) = Fe(:,:,fss);       % (fss-1)*(Scale*Angle)+fss)
            end
            
% Normalize features and handle NaNs            
            Size_Fs = size(Fs);
            
            for ss = 1:Size_Fs(1,2)
                Fs0(:,ss) = nDstrb1D(Fs(:,ss));
                % Fs0(:,ss) = Fs(:,ss);
            end

% Interpolate features onto a grid 
            for ss = 1:N_CWT
                Fs_(:,:,ss) = griddata(XI(:),YI(:),Fs0(:,ss),XI,YI,'nearest'); % CWT features Matrix
                global xv
                global yv
                in = inpolygon(XI,YI,xv,yv);
                Fs_ = in.*Fs_;
                Fs_(Fs_==0) = NaN;
            end

% Declare global variable for the CWT features matrix            
            clearvars -global CWT_Features_Matrix
            global  CWT_Features_Matrix
            CWT_Features_Matrix = Fs_;

% Prepare features for feature  extraction
            for vvv = 1:N_CWT
                Fs_2d_3 = Fs_(:,:,vvv); Fs_2d_3 = Fs_2d_3(:);
                Fs_2d_4(:,vvv) = Fs_2d_3(:); % CWT features Columns for ICA
            end
            
            global  CWT_Features_Columns
            CWT_Features_Columns = Fs_2d_4;
            

            % Store grid coordinates and features
            global XI_G
            global YI_G
            
            global  CWT_Features_Columns_input
            
            CWT_Features_Columns_input = [XI(:) YI(:) CWT_Features_Columns];
            CWT_Features_Columns_PPP = [XI(:) YI(:) CWT_Features_Columns];
            CWT_Features_Columns_PPP(any(isnan(CWT_Features_Columns_PPP), 2), :) = []; % Removing the NaNs
            
            XI_G = CWT_Features_Columns_PPP(:,1);
            YI_G = CWT_Features_Columns_PPP(:,2);
            CWT_Features_Columns = CWT_Features_Columns_PPP(:,3:end);
            
            % Update the status message in the GUI
            Message = sprintf('TOC erminée');
            set(handles.Status2, 'String', Message); % Onto GUI
            
            % Close the waitbar
             close(h)
             pause(0.01)

% % Reset the IsBusy state to allow future executions of the function
%     IsBusy = false;

        end

        % Button pushed function: ClearAll
        function ClearAll_Callback(app, event)
 % This function handles the callback for clearing all relevant data and resetting the app.
    

    % Persistent variable to ensure the function does not run multiple times simultaneously
    persistent IsBusy
    if isempty(IsBusy)
        IsBusy = false;
    end

    if IsBusy
        return;
    end

    IsBusy = true;

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            

            Parameter1 = str2num(get(handles.Parameter1,'String')); %  Run#


            % Supprimer les fichiers intermédiaires uniquement s'ils existent,
            % pour éviter les avertissements "File not found".
            for i = 1:Parameter1
                fname = ['X_Y_PData' num2str(i) '.csv'];
                if exist(fname,'file')
                    delete(fname);
                end
            end
            
            filesToDelete = { ...
                'XY_Redundant_Features.csv', ...
                'XY_Selected_Features.csv', ...
                'Selected_Features_N.csv', ...
                'XY_Estimated(MLP).csv', ...
                'XY_Training_Inputs.csv', ...
                'XY_All_Inputs.csv', ...
                'X_Y_TrgData1.csv', ...
                'XY_Training_Targets.csv', ...
                'X_Y_TrgData01.csv', ...
                'XY_Estimated(FSS).csv' ...
            };
            
            for k = 1:numel(filesToDelete)
                if exist(filesToDelete{k},'file')
                    delete(filesToDelete{k});
                end
            end
            
            close all
            % Relancer l’interface ELC2D sans effacer l’espace de travail MATLAB,
            % pour éviter les problèmes internes de callbacks.
            ELC2D

    % Reset the IsBusy state
    IsBusy = false;
        end

        % Button pushed function: CloseAll
        function CloseAll_Callback(app, event)
% This function handles the callback for closing all relevant data and resetting the app.


    persistent IsBusy
    if isempty(IsBusy)
        IsBusy = false;
    end

    if IsBusy
        return;
    end

    IsBusy = true;

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            Parameter1 = str2num(get(handles.Parameter1,'String')); %  Run#

            % Supprimer les fichiers intermédiaires uniquement s'ils existent,
            % pour éviter les avertissements "File not found".
            for i = 1:Parameter1
                fname = ['X_Y_PData' num2str(i) '.csv'];
                if exist(fname,'file')
                    delete(fname);
                end
            end
            
            filesToDelete = { ...
                'XY_Redundant_Features.csv', ...
                'XY_Selected_Features.csv', ...
                'Selected_Features_N.csv', ...
                'XY_Estimated(MLP).csv', ...
                'XY_Training_Inputs.csv', ...
                'XY_All_Inputs.csv', ...
                'X_Y_TrgData1.csv', ...
                'XY_Training_Targets.csv', ...
                'X_Y_TrgData01.csv', ...
                'XY_Estimated(FSS).csv' ...
            };
            
            for k = 1:numel(filesToDelete)
                if exist(filesToDelete{k},'file')
                    delete(filesToDelete{k});
                end
            end


    % Reset the IsBusy state
    IsBusy = false;
            % Fermer les figures sans effacer l’espace de travail MATLAB
            close all

        end

        % Value changed function: CoordsReads
        function CoordsReads_Callback(app, event)
% This function handles the callback for reading coordinates.

    persistent IsBusy
    if isempty(IsBusy)
        IsBusy = false;
    end

    if IsBusy
        return;
    end

    IsBusy = true;
    
    % Debug statement
    disp('CoordsReads_Callback start'); 

    value = app.CoordsReads.Value;
    disp(['Selected value: ', value]);

    % Create GUIDE-style callback args - Added by Migration Tool
    [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    h = waitbar(0.5, 'Coordinates Read...', 'WindowStyle', 'modal');
    frames = java.awt.Frame.getFrames();
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    % Clear various global variables used in the function

    clear global All_ImRtr
    clear global All_ImGtr
    clear global All_ImBtr
    clear global All_Sat_Matrix
    clear All_ImRtr
    clear All_ImGtr
    clear All_ImBtr
    clear All_Sat_Matrix

    clear global fh3
    clear fh3
    clear global fh6
    clear fh6
    clear global PPs
    clear PPs

    clear global All_Data_Matrix
    clear All_Data_Matrix
    clearvars -global

    clear global All_Trg_Matrix
    clear global All_Trg_Matrix0
    clear global CWT_Features_Columns_input
    clear global CWT_Features_Matrix
    clear global F1
    clear global FSS_Predicted
    clear global MLP_Predicted
    clear global MLP_Predicted
    clear global Redundant_Features_Matrix
    clear global Selected_Features_Matrix
    clear global Spectral_kICA_Features_Matrix
    clear global Spectral_nICA_Features_Matrix
    clear global Spectral_PCA_Features_Matrix
    clear global XY_Estimated
    clear global All_Data_Matrix
    clear global All_Data_Matrix2
    clear global All_ImBtr

 % Get the selected option from the dropdown menu

            contents = cellstr(get(hObject,'String'));
            pop_choice = contents(get(hObject,'Value'));
            pop_choice


            if (strcmp(pop_choice,'Rectangular coordinates'))
                % Handle the 'Rectangular coordinates' option

            [File_Name,Path_Name] = uigetfile({'*.txt'},'File Selector'); % Open file selection dialog
            combin3 = join([Path_Name,File_Name]);
            Coordinates = importdata(combin3)  % Import coordinates from the selected file
            
            % Update GUI components with the imported coordinates
            set(handles.Xmin, 'String', Coordinates(1)); % Onto GUI
            set(handles.Xmax, 'String', Coordinates(2)); % Onto GUI
            set(handles.Ymin, 'String', Coordinates(3)); % Onto GUI
            set(handles.Ymax, 'String', Coordinates(4)); % Onto GUI
            
            % Prepare coordinates for plotting
            x = [Coordinates(1);Coordinates(1);Coordinates(2);Coordinates(2)];
            y = [Coordinates(3);Coordinates(4);Coordinates(3);Coordinates(4)];
                Lat_mean = mean(y(:));
            

               % Calculate YI_Real_Ratio based on the latitude mean (to
               % have proportional vertical/horizontal view. 

                global YI_Real_Ratio
                YI_Real_Ratio = abs(1/(cos(deg2rad(Lat_mean))));
            
                % Define global variables for plotting
                global xv
                global yv
            

                % Calculate boundary of the coordinates
                bound = boundary(x,y,1);
                xv = x(bound);
                yv = y(bound);
            
             
                % Create a figure to plot the rectangular coordinates
                fig1 = fig(1);
                fig1.Name  = ('Outline of data: Rectangular coordinates');
                fig1.WindowState = 'maximized';
                plot(x(bound),y(bound))
                daspect([YI_Real_Ratio 1 1])
                        
            
            end
            
            % Close the waitbar
            close(h)
            pause(0.01);

    % Reset the IsBusy state to allow future executions of the function
    IsBusy = false;

        end

        % Button pushed function: Lineaments
        function Lineaments_Callback(app, event)
 % This function handles the callback for lineaments detection.

    % persistent IsBusy
    % if isempty(IsBusy)
    %     IsBusy = false;
    % end
    % 
    % if IsBusy
    %     return;
    % end
    % 
    % IsBusy = true;

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Lineaments Detection', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            global Parameter1
            global XI
            global YI
            global XI_G
            global YI_G
            global PCA_Features_Matrix
            global kICA_Features_Matrix
            global nICA_Features_Matrix
            global  CWT_Features_Matrix
            global  Spectral_PCA_Features_Matrix
            global  Spectral_kICA_Features_Matrix
            global  Spectral_nICA_Features_Matrix
            global All_Sat_Matrix
            global All_Data_Matrix
            global X_Pix
            global Y_Pix
            global curv

            % Read values from the GUI components
            curv = str2num(get(handles.edit98,'String'));
            X_Pix = str2num(get(handles.X_Pix,'String'));
            Y_Pix = str2num(get(handles.Y_Pix,'String'));
            sigma2 = str2num(get(handles.Sigma2,'String'));
            
            % Get checkbox values
            value2 = get(handles.checkbox2, 'Value');
            value6 = get(handles.checkbox6, 'Value');
            value7 = get(handles.checkbox7, 'Value');
            
            
            clearvars Input1
            clearvars Input2
            clearvars Input3
            clearvars Input4
            clearvars Input5
            clearvars Input6
            clearvars Input7
            clearvars Input8
            clearvars Input9
            
            % Initialize input variables
            Input1 = XI(:)- XI(:); Input2 = XI(:)- XI(:); Input3 = XI(:)- XI(:); Input4 = XI(:)- XI(:);
            Input5 = XI(:)- XI(:); Input6 = XI(:)- XI(:); Input7 = XI(:)- XI(:); Input8 = XI(:)- XI(:); Input9 = XI(:)- XI(:);
            
            % Set inputs based on checkbox values
            
            
            if value2 == 1
            
                Input2 = All_Data_Matrix;
            else
                Input2 = NaN(Y_Pix,X_Pix);
            end


            if value6 == 1
            
                Input6 = CWT_Features_Matrix;
               else
                Input6 = NaN(Y_Pix,X_Pix);
            end
            
            if value7 == 1
            
                Input7 = Spectral_PCA_Features_Matrix;
              else
                Input7 = NaN(Y_Pix,X_Pix);
            
            end
            
            % Concatenate inputs into a 3D array            
            All_Inputs = cat(3,Input2,Input6,Input7);
            
            originalArray = rand(4, 4, 3);
            originalArray(:,:,2) = NaN;
            originalArray(:,:,3) = NaN;
            
            originalArrayNoNaNPages = All_Inputs;
            nanPageIndices = all(all(isnan(originalArrayNoNaNPages), 1), 2); % Remove pages of the array that are entirely NaN
            originalArrayNoNaNPages(:,:,nanPageIndices) = [];
            
            originalArrayNoNaNPages_Size = size(originalArrayNoNaNPages);
            
                 if ndims(originalArrayNoNaNPages)==2
                     % Determine the number of features used for lineaments detection
                   Number_of_Features_used_for_Lineaments_Detection = 1;
                 elseif ndims(originalArrayNoNaNPages)==3
                     Number_of_Features_used_for_Lineaments_Detection = originalArrayNoNaNPages_Size(1,3);
                 end
            
            
            clearvars -global grm
            global grm

            grm = str2num(get(handles.edit91,'String'));    % grammes: Read more values from the GUI components
            global Line_Res
            Line_Res = str2num(get(handles.edit99,'String'));
            global GSF_Angles
            GSF_Angles = str2num(get(handles.edit101,'String'));
            
            % Perform lineaments detection

            Lineaments_All(originalArrayNoNaNPages,Number_of_Features_used_for_Lineaments_Detection,sigma2);
            
            % Display the number of features used
            Number_of_Features_used = num2str(Number_of_Features_used_for_Lineaments_Detection);
            % disp(['Number of Features used: ', Number_of_Features_used]);


            close(h)
            pause(0.1);

    % % Reset the IsBusy state
    % IsBusy = false;

        end

        % Callback function
        function Mother_WL_Callback(app, event)
 % This function handles the callback for selecting the mother wavelet.            

    persistent IsBusy
    if isempty(IsBusy)
        IsBusy = false;
    end

    if IsBusy
        return;
    end

    IsBusy = true;
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            clearvars pop_choice2
            
            global pop_choice2
            
            contents2 = cellstr(get(hObject,'String'));
            pop_choice2 = contents2(get(hObject,'Value'));

             % Map the selected option to its corresponding code
            
                if (strcmp(pop_choice2,'Derivatives of Gaussian (Asymmetric)'))
            pop_choice2 = cell({'gaus'}); pop_choice2  = cell2mat(pop_choice2);
            
                elseif (strcmp(pop_choice2,'Mexican Hat (Symmetric)'))
            pop_choice2 = cell({'mexh'}); pop_choice2  = cell2mat(pop_choice2);
 
                elseif (strcmp(pop_choice2,'Cauchy (Asymmetric)'))
            pop_choice2 = cell({'cauchy'}); pop_choice2  = cell2mat(pop_choice2);
                        
                end
            pop_choice2
            
            % Read the scales from the GUI component
            Scales = str2num(get(handles.Scales,'String'));

    % Reset the IsBusy state
    IsBusy = false;

        end

        % Value changed function: PointData
        function PointData_Callback(app, event)
% This function handles the callback for loading and processing point data.

    persistent IsBusy
    if isempty(IsBusy)
        IsBusy = false;
    end

    if IsBusy
        return;
    end

    IsBusy = true;            

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Loading...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

             % Turn off warnings
            warning('off','all')

            % Get the selected option from the dropdown menu
            contents = cellstr(get(hObject,'String'));
            pop_choice = contents(get(hObject,'Value'));
            pop_choice
            
            % Open file selection dialog and read the selected CSV file
            [File_Name,Path_Name] = uigetfile('*.csv');
            combin2 = join([Path_Name,File_Name]);
            Data1 = csvread(combin2);

            % Determine min and max values for x and y coordinates
            Xmin1 = min(Data1(:,1));
            Xmax1 = max(Data1(:,1));
            Ymin1 = min(Data1(:,2));
            Ymax1 = max(Data1(:,2));

            % Get the user-defined min and max values for x and y coordinates
            Xmin = str2num(get(handles.Xmin,'String'));
            Xmax = str2num(get(handles.Xmax,'String'));
            Ymin = str2num(get(handles.Ymin,'String'));
            Ymax = str2num(get(handles.Ymax,'String'));

            % Calculate pixel spacing and number of pixels
            Spacing_X = (str2num(get(handles.XSpacing,'String')))/3600;      % Arc-Second to degree
            Spacing_Y = (str2num(get(handles.XSpacing,'String')))/3600;      % Arc-Second to degree
            XPixels = ceil((Xmax - Xmin)/Spacing_X);
            YPixels = ceil((Ymax - Ymin)/Spacing_Y);
            
            % Update GUI components with pixel values
            set(handles.X_Pix, 'String', XPixels); % Onto GUI
            set(handles.Y_Pix, 'String', YPixels); % Onto GUI
            
            % Get the line resolution and update GUI
            Line_Res = str2num(get(handles.edit99,'String'));
            set(handles.edit91, 'String', round(0.1*Line_Res,0)); % Onto GUI
            
            % Get sigma value from GUI
            sigma1 = str2num(get(handles.Sigma1,'String'));
            
             % Generate a grid for the coordinates
            xx = Xmin:Spacing_X:Xmax;
            yy = Ymin:Spacing_Y:Ymax;
            global XI
            global YI
            [XI,YI] = meshgrid(xx,yy);
            
            % Get the number of data sets to process
            global Parameter1
            Parameter1 = str2num(get(handles.Parameter1,'String')); %  Data#
            

             % Close the loading waitbar
            close(h)
            pause(0.1);
            

             % Display a waitbar indicating the processing step
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Processing...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Extract data columns
            x = Data1(:,1);
            y = Data1(:,2);
            D1 = Data1(:,3);
            global min_D1
            global max_D1
            
            % Determine the min and max values of the data
            min_D1 = min(D1);
            max_D1 = max(D1);
            D4 = nDstrb1D(D1); % Normalize the data
            
            % Interpolate the data based on the selected option
            if (strcmp(pop_choice,'Regular'))
                ImD0 = griddata(x,y,D4,XI,YI,'nearest');
            elseif (strcmp(pop_choice,'Scattered'))
                F = scatteredInterpolant(x,y,D4,'natural');
                ImD0 = F(XI,YI);
            end
            
            % Apply a filter to the interpolated data
            ImD1tr0  = FilterB(ImD0,sigma1);
            
            % Apply a polygon mask to the filtered data
            global xv
            global yv
            in = inpolygon(XI,YI,xv,yv);
            ImD1tr = in.*ImD1tr0;
            ImD1tr(ImD1tr==0) = NaN;
            
            % Close the processing waitbar
            close(h)
            pause(0.01);
            
            % Display a waitbar indicating the saving step
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Save Results...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Save the processed data to the global variable
            global All_Data_Matrix
            
            All_Data_Matrix(:,:,Parameter1) = ImD1tr;
            
            % Prepare data for saving
            Datatr = [XI(:) YI(:) ImD1tr(:)];
            delete(['X_Y_PData' num2str(Parameter1) '.csv'])
            csvwrite(['X_Y_PData' num2str(Parameter1) '.csv'],Datatr);
            fileattrib(['X_Y_PData' num2str(Parameter1) '.csv'], '+h +w -a');
            
            % Remove NaN values from the data
            X = XI(:); Y = YI(:); ImD1tr__ = ImD1tr(:); 
            Datatr2 = [X Y ImD1tr__];
                    Datatr2(any(isnan(Datatr2), 2), :) = []; % Removing the NaNs
                    X(any(isnan(X), 2), :) = []; % Removing the NaNs
                    Y(any(isnan(Y), 2), :) = []; % Removing the NaNs
                    ImD1tr__(any(isnan(ImD1tr__), 2), :) = []; % Removing the NaNs
            
            % Save the cleaned data        
            delete(['X_Y_PData_' num2str(Parameter1) '.csv'])
            csvwrite(['X_Y_PData_' num2str(Parameter1) '.csv'],Datatr2);
            fileattrib(['X_Y_PData_' num2str(Parameter1) '.csv'], '+h +w -a');
            
            % Close the saving waitbar
            close(h)
            pause(0.01);

    % Reset the IsBusy state to allow future executions of the function
    IsBusy = false;

        end

        % Button pushed function: S_PCA
        function S_PCA_Callback(app, event)
 % This function handles the callback for performing Spectral PCA calculations.
    persistent IsBusy
    if isempty(IsBusy)
        IsBusy = false;
    end

    if IsBusy
        return;
    end

    IsBusy = true;

            %% S-PCA
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0,'Spectral PCA Calculations...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            global XI
            global YI
            global CWT_Features_Columns
            
            % Get the dimension reduction value from the GUI
            D_Reduct = str2num(get(handles.D_Reduct,'String'))
            
            % Perform PCA on the CWT features
            [S_PCA_pp0, U, mu] = PCA(CWT_Features_Columns',D_Reduct);
            S_PCA_pp00 = S_PCA_pp0';
            
            % Normalize the PCA components
            for ss = 1:D_Reduct
                S_PCA_pp(:,ss) = nDstrb1D(S_PCA_pp00(:,ss));
            end
            
            close(h)
            pause(0.01);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Save Results...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            global XI_G
            global YI_G
            
            % Preallocate a grid for the PCA components
            siu = size(XI);
            S_PCA_pp_Grid = zeros(siu(1,1),siu(1,2),D_Reduct); % Preallocating
            
            % Interpolate the PCA components onto the grid
            for ss = 1:D_Reduct
                S_PCA_pp_Grid(:,:,ss) = griddata(XI_G(:),YI_G(:),S_PCA_pp(:,ss),XI,YI,'nearest'); % Fs_2d_nFastICA_Grid(:,:,uuu) = imgaussfilt3(Fs_2d_nFastICA_Grid(:,:,uuu),sigma4);
                global xv
                global yv
                in = inpolygon(XI,YI,xv,yv);
                S_PCA_pp_Grid = in.*S_PCA_pp_Grid;
                S_PCA_pp_Grid(S_PCA_pp_Grid==0) = NaN;
            end
            
            % Save the PCA grid to a global variable
            global  Spectral_PCA_Features_Matrix
            Spectral_PCA_Features_Matrix = S_PCA_pp_Grid;
                       
            % Update the GUI with a completion message
            Message = sprintf('ACPS Terminée');
            set(handles.Status2, 'String', Message); % Onto GUI
            
            close(h)
            pause(0.01)

    % Reset the IsBusy state
    IsBusy = false;

        end

        % Value changed function: popupmenu13
        function popupmenu13_Callback(app, event)
 % This function handles the callback for uploading and processing the digitized lineaments

    % persistent IsBusy
    % if isempty(IsBusy)
    %     IsBusy = false;
    % end
    % 
    % if IsBusy
    %     return;
    % end
    % 
    % IsBusy = true;

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            contents = cellstr(get(hObject,'String'));
            pop_choice = contents(get(hObject,'Value'));
            pop_choice
            
            % Get user-defined coordinates from the GUI
            Xmin = str2num(get(handles.Xmin,'String'));
            Xmax = str2num(get(handles.Xmax,'String'));
            Ymin = str2num(get(handles.Ymin,'String'));
            Ymax = str2num(get(handles.Ymax,'String'));
            
            % Calculate pixel spacing and number of pixels
            Spacing_X = (str2num(get(handles.edit72,'String')))/3600;      % Arc-Second to degree
            Spacing_Y = (str2num(get(handles.edit72,'String')))/3600;      % Arc-Second to degree
            
            
            XPixels = ceil((Xmax - Xmin)/Spacing_X);
            YPixels = ceil((Ymax - Ymin)/Spacing_Y);
            set(handles.pushbutton38, 'String', XPixels); % Onto GUI
            set(handles.pushbutton37, 'String', YPixels); % Onto GUI
           
            % Generate a grid for the coordinates
            xx = Xmin:Spacing_X:Xmax;
            yy = Ymin:Spacing_Y:Ymax;
            
            global XI_
            global YI_
            [XI_,YI_] = meshgrid(xx,yy);
            
            Parameter1 = 1; %  Data#


            if (strcmp(pop_choice,'Point Data'))
                % Handle the 'Point Data' option
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Loading...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Open file selection dialog and read the selected CSV file
            [File_Name,Path_Name] = uigetfile('*.csv');
            combin2 = join([Path_Name,File_Name]);
            Data1 = csvread(combin2);
            
            close(h)
            pause(0.1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Processing...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
             % Extract data columns
            x = Data1(:,1);
            y = Data1(:,2);
            D1 = Data1(:,3);
            D1 = nDstrb1D(D1);
            Lat_mean = mean(y(:));
            
            global xv_tr
            global yv_tr
            global xv
            global yv
            
             % Calculate boundary for the data points
                bound = boundary(x,y,1);
                xv_tr = x(bound);
                yv_tr = y(bound);
            
            % Interpolate the data onto the grid    
            ImD4 = griddata(x,y,D1,XI_,YI_,'natural');
            
            % Apply a filter to the interpolated data
            sigma_target = str2num(get(handles.edit73,'String'));
                   ImTrgtr0  = FilterB(ImD4,sigma_target);
            
            % Apply polygon mask to the data       
            in = inpolygon(XI_,YI_,xv,yv);
            in2 = inpolygon(XI_,YI_,xv_tr,yv_tr);
            ImTrgtr1 = in.*ImTrgtr0;
            ImTrgtr1(ImTrgtr1==0) = NaN;
            
            ImTrgtr = in2.*ImTrgtr1;
            ImTrgtr(ImTrgtr==0) = NaN;
            size_ImTrgtr1 = size(ImTrgtr);
            
            % Determine cutoff values for the data
            Cuttoff1 = min(ImTrgtr(:));
            
            set(handles.edit89, 'String', Cuttoff1);
            Cuttoff1 = (str2num(get(handles.edit89,'String')));
            
             % Apply cutoffs to the data
            editString = get(handles.edit103, 'String');
            
            if isempty(editString)
            
            for i = 2 : size_ImTrgtr1(1,1)
                for j = 1 : size_ImTrgtr1(1,2)
                    if ImTrgtr(i,j) < Cuttoff1
                        ImTrgtr(i,j) = NaN;
                    end
                end
            end
            
            
            else
                Cuttoff2 = (str2num(get(handles.edit103,'String')));
            
                for i = 2 : size_ImTrgtr1(1,1)
                for j = 1 : size_ImTrgtr1(1,2)
                    if ImTrgtr(i,j) < Cuttoff2
                        ImTrgtr(i,j) = NaN;
                    end
                end
            end
            
            end
            
            clear i j
            
            close(h)
            pause(0.01);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Save Results...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            global All_Trg_Matrix0
            global All_Trg_Matrix
            
            All_ImTrgtr0(:,:,Parameter1) = ImTrgtr0;
            All_Trg_Matrix0 = All_ImTrgtr0;
            
            Trgtr0 = [XI_(:) YI_(:) ImTrgtr0(:)];
            delete(['X_Y_TrgData0' num2str(Parameter1) '.csv'])
            csvwrite(['X_Y_TrgData0' num2str(Parameter1) '.csv'],Trgtr0);
            fileattrib(['X_Y_TrgData0' num2str(Parameter1) '.csv'], '+h +w -a');
            
            
            All_ImTrgtr(:,:,Parameter1) = ImTrgtr;
            All_Trg_Matrix = All_ImTrgtr;
            
            Trgtr = [XI_(:) YI_(:) ImTrgtr(:)];
            delete(['X_Y_TrgData' num2str(Parameter1) '.csv'])
            csvwrite(['X_Y_TrgData' num2str(Parameter1) '.csv'],Trgtr);
            fileattrib(['X_Y_TrgData' num2str(Parameter1) '.csv'], '+h +w -a');
            
            Trgtr(any(isnan(Trgtr), 2), :) = []; % Removing the NaNs
            delete XY_Training_Targets.csv
            csvwrite('XY_Training_Targets.csv',Trgtr);
            fileattrib('XY_Training_Targets.csv', '+h +w -a');


            elseif (strcmp(pop_choice,'Image'))
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0,'Loading...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            Parameter1 = 1; %  Image#
            
            % Open file selection dialog for faults image
            [File_Name,Path_Name] = uigetfile({'*.jpg';'*.png';'*.bmp'},'File Selector');
            combin = join([Path_Name,File_Name]);
            SatImage = imread(combin);
            
            % Open file selection dialog for coordinates
            [File_Name,Path_Name] = uigetfile({'*.txt'},'File Selector');
            combin3 = join([Path_Name,File_Name]);
            Coordinates_Sat = importdata(combin3);
            Xmin_S = Coordinates_Sat(1);
            Xmax_S = Coordinates_Sat(2);
            Ymin_S = Coordinates_Sat(3);
            Ymax_S = Coordinates_Sat(4);
            
            % Calculate pixel spacing and number of pixels
            Spacing_X = (str2num(get(handles.edit72,'String')))/3600;      % Arc-Second to degree
            Spacing_Y = (str2num(get(handles.edit72,'String')))/3600;      % Arc-Second to degree
            
            XPixels = ceil((Xmax_S - Xmin_S)/Spacing_X);
            YPixels = ceil((Ymax_S - Ymin_S)/Spacing_Y);
            
            % Resize and process the faults image
            SatImage = imresize(SatImage,[XPixels YPixels]);
            
            Image_Gray = im2double(rgb2gray(SatImage));      % Gray
            Image_Gray = imcomplement(Image_Gray);
            Gray2 = Image_Gray(:);
            
            
            [m,n] = size(Image_Gray);
            [x,y] = meshgrid(Xmin_S:(Xmax_S-Xmin_S)/(n-1):Xmax_S,Ymax_S:-(Ymax_S-Ymin_S)/(m-1):Ymin_S); % build x and y
            
            close(h)
            pause(0.01);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Processing...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
             % Combine coordinates and red channel values into one matrix and remove NaNs
            XYZ_Gray_RGB = [x(:) y(:) Gray2];        % put all together in lat/lon
            XYZ_Gray_RGB(any(isnan(XYZ_Gray_RGB), 2), :) = []; % Removing the NaNs
            
            Red1 = XYZ_Gray_RGB(:,3);
            Red3 = nDstrb1D(Red1);
            
            Lat_mean = mean(y(:));
            
            global xv_tr
            global yv_tr
            global xv
            global yv
            
                bound = boundary(x(:),y(:),1);
                xv_tr = x(bound);
                yv_tr = y(bound);
            
            
            global XI_
            global YI_
            global xv
            global yv
            sigma_target = str2num(get(handles.edit73,'String'));
            ImR = griddata(XYZ_Gray_RGB(:,1),XYZ_Gray_RGB(:,2),Red3,XI_,YI_,'nearest');
            ImTrgtr0  = FilterB(ImR,sigma_target);
            
            in = inpolygon(XI_,YI_,xv,yv);
            in2 = inpolygon(XI_,YI_,xv_tr,yv_tr);
            ImTrgtr1 = in.*ImTrgtr0;
            ImTrgtr1(ImTrgtr1==0) = NaN;
            
            ImTrgtr = in2.*ImTrgtr1;
            ImTrgtr(ImTrgtr==0) = NaN;
            
            size_ImTrgtr1 = size(ImTrgtr);
            
            Cuttoff1 = min(ImTrgtr(:));
            
            set(handles.edit89, 'String', Cuttoff1);
            Cuttoff1 = (str2num(get(handles.edit89,'String')));
            
            editString = get(handles.edit103, 'String');
            
            if isempty(editString)
            
            for i = 2 : size_ImTrgtr1(1,1)
                for j = 1 : size_ImTrgtr1(1,2)
                    if ImTrgtr(i,j) < Cuttoff1
                        ImTrgtr(i,j) = NaN;
                    end
                end
            end
            
            
            else
                Cuttoff2 = (str2num(get(handles.edit103,'String')));
            
                for i = 2 : size_ImTrgtr1(1,1)
                for j = 1 : size_ImTrgtr1(1,2)
                    if ImTrgtr(i,j) < Cuttoff2
                        ImTrgtr(i,j) = NaN;
                    end
                end
            end
            
            end
                        
            
            clear i j
            
            
            close(h)
            pause(0.01);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Save Results...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            global All_Trg_Matrix0
            global All_Trg_Matrix
            
            All_ImTrgtr0(:,:,Parameter1) = ImTrgtr0;
            All_Trg_Matrix0 = All_ImTrgtr0;
            
            Trgtr0 = [XI_(:) YI_(:) ImTrgtr0(:)];
            delete(['X_Y_TrgData0' num2str(Parameter1) '.csv'])
            csvwrite(['X_Y_TrgData0' num2str(Parameter1) '.csv'],Trgtr0);
            fileattrib(['X_Y_TrgData0' num2str(Parameter1) '.csv'], '+h +w -a');
            
            
            All_ImTrgtr(:,:,Parameter1) = ImTrgtr;
            All_Trg_Matrix = All_ImTrgtr;
            
            Trgtr = [XI_(:) YI_(:) ImTrgtr(:)];
            delete(['X_Y_TrgData' num2str(Parameter1) '.csv'])
            csvwrite(['X_Y_TrgData' num2str(Parameter1) '.csv'],Trgtr);
            fileattrib(['X_Y_TrgData' num2str(Parameter1) '.csv'], '+h +w -a');
            Trgtr(any(isnan(Trgtr), 2), :) = []; % Removing the NaNs
            delete XY_Training_Targets.csv
            csvwrite('XY_Training_Targets.csv',Trgtr);
            fileattrib('XY_Training_Targets.csv', '+h +w -a');
            
                        
            close(h)
            pause(0.01);

            end


    % % Reset the IsBusy state
    % IsBusy = false;


        end

        % Value changed function: popupmenu5
        function popupmenu5_Callback(app, event)

    persistent IsBusy
    if isempty(IsBusy)
        IsBusy = false;
    end

    if IsBusy
        return;
    end

    IsBusy = true;

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Plot Results...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            contents = cellstr(get(hObject,'String'));
            pop_choice = contents(get(hObject,'Value'));
            pop_choice
            
            sigma2 = str2num(get(handles.Sigma2,'String'));
            N_Scales = str2num(get(handles.N_Scales,'String'));
            N_Angles = str2num(get(handles.N_Angles,'String'));
            Parameter1 = str2num(get(handles.Parameter1,'String'));
            D_Reduct = str2num(get(handles.D_Reduct,'String'));
            N_CWT = str2num(get(handles.N_CWT,'String'));
            CWT_plt = ceil(N_CWT^0.5);
            numberoffeatures2 = N_CWT/(N_Scales*N_Angles);
            D_Reduct = str2num(get(handles.D_Reduct,'String'));
            N_CWT = str2num(get(handles.N_CWT,'String'));
            N_S_CWT_Features = round((N_CWT * D_Reduct / 100),0);
            Xmin = str2num(get(handles.Xmin,'String'));
            Ymin = str2num(get(handles.Ymin,'String'));
            Xmax = str2num(get(handles.Xmax,'String'));
            Ymax = str2num(get(handles.Ymax,'String'));
            
            global XI
            global YI
            global XI_
            global YI_
            global xv
            global yv
            global PCA_pp_PC
            global kFastICA_pp_IC
            global nFastICA_pp_IC
            global YI_Real_Ratio
            global RR
            global GG
            global BB
            global mR
            global mG
            global mB
            global Num_Selected_Fs1
            global All_ImRtr
            global All_ImGtr
            global All_ImBtr
            global All_Sat_Matrix
            global All_Data_Matrix
            % global All_Data_Matrix2
            global PCA_Features_Matrix
            global kICA_Features_Matrix
            global nICA_Features_Matrix
            global  Selected_Features_Matrix
            global  F1
            global  Redundant_Features_Matrix
            global  CWT_Features_Matrix
            global  Spectral_PCA_Features_Matrix
            global  Spectral_kICA_Features_Matrix
            global  Spectral_nICA_Features_Matrix
            global  All_Trg_Matrix0
            global  All_Trg_Matrix
            global  MLP_Predicted
            global  Selected_Features_Matrix
            global  F1
            global  Redundant_Features_Matrix
            global  FSS_Predicted
            global  XY_Estimated
            global Fe_All
            global N_Fs
            clearvars -global grm
            global grm
            grm = str2num(get(handles.edit91,'String'));    % grammes: The minimum distance between two parallel linear patterns
            

            f1 = figure(1);
           
                
             if (strcmp(pop_choice,'Données ponctuelles'))
                 close Figure 1
                 f1 = figure(1);
                 f1.Name  = ('Données ponctuelles');
                 f1.WindowState = 'maximized';
            
                 Surf1(All_Data_Matrix,Parameter1,sigma2);


             elseif (strcmp(pop_choice,'TOC'))
            
                 close Figure 1
                 f1 = figure(1);
                 f1.Name  = ('TOC');
                 f1.WindowState = 'maximized';
                 Surf1(CWT_Features_Matrix,N_CWT,sigma2);
                        
             elseif (strcmp(pop_choice,'TOC (Inverser les couleurs)'))
            
                 close Figure 1
                 f1 = figure(1);
                 f1.Name  = ('TOC (Inverser les couleurs)');
                 f1.WindowState = 'maximized';
                 Surf1_Rev(CWT_Features_Matrix,N_CWT,sigma2);


             elseif (strcmp(pop_choice,'ACPS'))
            
                 close Figure 1
                 f1 = figure(1);
                 f1.Name  = ('ACPS');
                 f1.WindowState = 'maximized';
            
                 if ndims(Spectral_PCA_Features_Matrix)==2
                     NOF = 1;
                 elseif ndims(Spectral_PCA_Features_Matrix)==3
                 NOF = size(Spectral_PCA_Features_Matrix); NOF = NOF(1,3);
                 end
            
                 Spectral_PCA_Features_Matrix_size = size(Spectral_PCA_Features_Matrix);
                 Surf1(Spectral_PCA_Features_Matrix,NOF,sigma2);


             elseif (strcmp(pop_choice,'ACPS (Inverser les couleurs)'))
            
                 close Figure 1
                 f1 = figure(1);
                 f1.Name  = ('ACPS (Inverser les couleurs)');
                 f1.WindowState = 'maximized';
                 Spectral_PCA_Features_Matrix_size = size(Spectral_PCA_Features_Matrix);
                 Surf1_Rev(Spectral_PCA_Features_Matrix,Spectral_PCA_Features_Matrix_size(1,3),sigma2);


            elseif (strcmp(pop_choice,'Cibles'))
                 close Figure 1
                 f1 = figure(1);
                 f1.Name  = ('Machine Learning Targets');
                 f1.WindowState = 'maximized';
            
                        All_Trg_Matrix2 = FilterB(All_Trg_Matrix,sigma2);
            %             All_Trg_Matrix2 = imbinarize(All_Trg_Matrix2); All_Trg_Matrix2 = im2double(All_Trg_Matrix2);
                        surf(XI_,YI_,All_Trg_Matrix2)
                        title('Cibles')
                        box on
                        set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                        colormap jet;
                        axis equal
                        shading interp
                        view(0,90)
                        grid off
                        daspect([YI_Real_Ratio 1 1])
                        xlim([Xmin Xmax])
                        ylim([Ymin Ymax])


            elseif (strcmp(pop_choice,'Score F-beta'))
                 close Figure 1
                 f1 = figure(1);
                 f1.Name  = ('Score F-beta');
                 f1.WindowState = 'maximized';
            
            global plotH1_rgb
            
            % plotH1_rgb_size = size(plotH1_rgb)
            
                        All_Trg_Matrix2 = FilterB(All_Trg_Matrix,sigma2);
                        All_Trg_Matrix2 = imbinarize(All_Trg_Matrix2);
            %             All_Trg_Matrix2 = imcomplement(All_Trg_Matrix2);
                        All_Trg_Matrix2 = im2double(All_Trg_Matrix2);
            
            global X_Pix
            global Y_Pix
            global Line_Res
            
            % Line_Res
            % min(X_Pix,Y_Pix)
            %
            % All_Trg_Matrix2_size = size(All_Trg_Matrix2)
            plotH1_rgb_size = size(plotH1_rgb);
            
            All_Trg_Matrix2 = imresize(All_Trg_Matrix2,[plotH1_rgb_size(1,1) plotH1_rgb_size(1,2)]);
            All_Trg_Matrix2_size = size(All_Trg_Matrix2)
            data1 = FilterB(All_Trg_Matrix2,sigma2);
            data1 = imbinarize(data1);
            data1 = im2double(data1);
            data1 = cat(3, data1, data1, data1);
            data2 = imcomplement(data1);
            plotH1_rgb2 = imcomplement(plotH1_rgb);
            data2_size = size(data2)
            
            
            % Read the binary images
            real_faults = (data1(:,:,1)+data1(:,:,2)+data1(:,:,3))/3;
            detected_faults = (plotH1_rgb2(:,:,1)+plotH1_rgb2(:,:,2)+plotH1_rgb2(:,:,3))/3;
            
            radius = 1;
            fprintf('Radius =\n\n       %d\n', radius);
            
            radius1 = 10
            radius2 = radius1+radius
            beta = 0.2
            
                % Create a disk-shaped structuring element of the given radius
                se1 = strel('rectangle', [radius1 radius1]);
                se2 = strel('rectangle', [radius2 radius2]);
                % Dilate the real faults image to create a neighborhood of radius = 5 around the fault lines
                 real_faults_neighborhood1 = imdilate(real_faults, se1);
                 real_faults_neighborhood2 = imdilate(real_faults, se2);
            
            
                % Apply the neighborhood to the detected faults image
            %     detected_faults_neighborhood = detected_faults & real_faults_neighborhood;
                detected_faults_neighborhood = detected_faults;
            
                % Flatten the neighborhood images into vectors for computation
                real_faults_neighborhood1 = real_faults_neighborhood1(:);
                real_faults_neighborhood2 = real_faults_neighborhood2(:);
            
                detected_faults_neighborhood = detected_faults_neighborhood(:);
            
                % True positives
                tp = sum(real_faults_neighborhood1 & detected_faults_neighborhood);
                fprintf('True Positives =\n\n       %d\n', tp);
            
                % False positives
                fp1 = sum(~real_faults_neighborhood1 & detected_faults_neighborhood);
                fp2 = sum(~real_faults_neighborhood2 & detected_faults_neighborhood);
            
                fp = fp1 - fp2;
                fprintf('False Positives =\n\n       %d\n', fp);
            
                % False negatives
                fn = sum(real_faults_neighborhood1 & ~detected_faults_neighborhood);
                fprintf('False  Negatives =\n\n       %d\n', fn);
            
                % Precision
                format bank
                precision = tp / (tp + fp);
                Precision = precision
            
            
                % Recall
                format bank
                recall = tp / (tp + fn);
                Recall = recall
            
                % Fbeta Score
                format bank
                f1_score = 2 * ((precision * recall) / (precision + recall));
                fbeta_score = (1 + beta^2) * ((precision * recall) / ((beta^2 * precision) + recall));
                Fbeta = fbeta_score


                    subplot(1,2,1),
                    plot(XI,YI)
                        image(data2,'XData',[min(XI(:)) max(XI(:))],'YData',[min(YI(:)) max(YI(:))]);
                        title('Observed Lineaments')
                        set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                        colormap jet;
                        set(gca,'YDir','normal')
                        shading interp
                        view(0,90)
                        grid off
                        ax = gca;
                        box on
                        ax.TickDir = 'out';
            %             h.AlignVertexCenters = 'on';
                        daspect([YI_Real_Ratio 1 1])
            
            
                        subplot(1,2,2),
                    plot(XI,YI)
                        image(plotH1_rgb,'XData',[min(XI(:)) max(XI(:))],'YData',[min(YI(:)) max(YI(:))]);
                        title(['Estimated Lineaments,  F-Beta Score = ',num2str(mean(fbeta_score)),'']);
            %             subtitle(['True Positives = ', num2str(tp), ', False Positives = ', num2str(fp), ', False Negatives = ', num2str(fn)]);
                        set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                        colormap jet;
                        set(gca,'YDir','normal')
                        shading interp
                        view(0,90)
                        grid off
                        ax = gca;
                        box on
                        ax.TickDir = 'out';
            %             h.AlignVertexCenters = 'on';
                        daspect([YI_Real_Ratio 1 1])
            
            
                        % Create a filename with the current date and time
                        filename = sprintf('figure_%s.fig', datestr(now, 'mmddyy_HHMMSS'));
            
                        % Save the figure
                        saveas(gcf, filename);
            
            % All Parameters For Lineaments Assessment
            
            Spacing = str2num(get(handles.XSpacing,'String'));
            Sigma1 = str2num(get(handles.Sigma1,'String'));
            N_Scales = str2num(get(handles.N_Scales,'String'));
            Scale_D = str2num(get(handles.Scale_D,'String'));
            Sigma3 = str2num(get(handles.Sigma3,'String'));
            N_Angles = str2num(get(handles.N_Angles,'String'));
            edit100 = str2num(get(handles.edit100,'String'));
            N_CWT = str2num(get(handles.N_CWT,'String'));
            D_Reduct = str2num(get(handles.D_Reduct,'String'));
            edit92 = str2num(get(handles.edit92,'String'));
            edit93 = str2num(get(handles.edit93,'String'));
            edit99 = str2num(get(handles.edit99,'String'));
            edit91 = str2num(get(handles.edit91,'String'));
            edit101 = str2num(get(handles.edit101,'String'));
            edit98 = str2num(get(handles.edit98,'String'));
            
            date_value = date; % Returns the current date as a string
            time_value = datestr(now, 'HH:MM:SS'); % Returns the current time as a string
            
            % Create cell arrays for the variable names and the values
            variable_names = {'Spacing'; 'Data Filter'; 'Number of Scales'; 'Scale Dilation'; 'CWT Filter'; 'Number of CWT Angles'; 'Mother Wavelet Symetry'; 'Number of CWT Features'; 'CWT DR'; 'Order X'; 'Order Y'; 'Lineaments Resolution'; 'Step  Filtering Width'; 'Step Filtering Number of Angles'; 'Variance of Step Filtering Width'; 'F Beta Score'};
            variable_values = [Spacing; Sigma1; N_Scales; Scale_D; Sigma3; N_Angles; edit100; N_CWT; D_Reduct; edit92; edit93; edit99; edit91; edit101; edit98; Fbeta];


             elseif (strcmp(pop_choice,'MLP Prediction'))
                 close Figure 1
                 f1 = figure(1);
                 f1.Name  = ('Machine Learning MLP Prediction');
                 f1.WindowState = 'maximized';
            
                        MLP_Predicted2 = FilterB(MLP_Predicted,sigma2);
                        surf(XI,YI,MLP_Predicted2)
                        title('Predicted by MLP')
                        box on
                        set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                        colormap jet;
                        axis equal
                        shading interp
                        view(0,90)
                        grid off
                        daspect([YI_Real_Ratio 1 1])
            
            
             elseif (strcmp(pop_choice,'MLP Lineaments'))
            
                 Lineaments_All(MLP_Predicted,1,sigma2);
            
            
             elseif (strcmp(pop_choice,'FSS Prediction'))
                 close Figure 1
                 f1 = figure(1);
                 f1.Name  = ('Machine Learning FSS Prediction');
                 f1.WindowState = 'maximized';
            
                        FSS_Predicted2 = FilterB(FSS_Predicted,sigma2);
                        surf(XI,YI,FSS_Predicted2)
                        title('Predicted by FSS')
                        box on
                        set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                        colormap jet;
                        axis equal
                        shading interp
                        view(0,90)
                        grid off
                        daspect([YI_Real_Ratio 1 1])
            
             elseif (strcmp(pop_choice,'FSS Lineaments'))
            
            plotH = Lineaments2(FSS_Predicted);


             elseif (strcmp(pop_choice,'Selected Features'))
                 close Figure 1
                 f1 = figure(1);
                 f1.Name  = ('Machine Learning Selected Features');
                 f1.WindowState = 'maximized';
                 Selected_Features = csvread('Selected_Features_N.csv');
                 Num_Selected_Features = size(Selected_Features); Num_Selected_Features = Num_Selected_Features(1,2);
            
                 FSS_plt = ceil(Num_Selected_Features^0.5);
                    for tttt11 = 1:Num_Selected_Features
                        Selected_Features_Matrix2(:,:,tttt11) = FilterB(Selected_Features_Matrix(:,:,tttt11),sigma2);
                        subplot(FSS_plt,FSS_plt,tttt11),
                        surf(XI,YI,Selected_Features_Matrix2(:,:,tttt11))
                        title(['Selected Feature ',num2str(tttt11),''])
                        box on
                        set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                        colormap jet;
                        axis equal
                        shading interp
                        view(0,90)
                        grid off
                        daspect([YI_Real_Ratio 1 1])
                    end
            
             elseif (strcmp(pop_choice,'Redundant Features'))
                 close Figure 1
                 f1 = figure(1);
                 f1.Name  = ('Machine Learning Redundant Features');
                 f1.WindowState = 'maximized';
                 global Num_Redundant_Features1
                 global Num_Selected_Features1
                 Num_Selected_Features = Num_Redundant_Features1
                 FSS_plt = ceil(Num_Selected_Features^0.5);
                    for tttt11 = 1:Num_Selected_Features
                        Selected_Features_Matrix2(:,:,tttt11) = FilterB(Redundant_Features_Matrix(:,:,tttt11),sigma2);
                        subplot(FSS_plt,FSS_plt,tttt11),
                        surf(XI,YI,Selected_Features_Matrix2(:,:,tttt11))
                        title(['Redundant Feature ',num2str(tttt11),''])
                        box on
                        set(gca,'TickDir','out','linewidth',1,'Layer', 'top');
                        colormap jet;
                        axis equal
                        shading interp
                        view(0,90)
                        grid off
                        daspect([YI_Real_Ratio 1 1])
                    end
            end
            
            close(h)
            pause(0.01);

    % Reset the IsBusy state
    IsBusy = false;

        end

        % Button pushed function: pushbutton44
        function pushbutton44_Callback(app, event)

    % persistent IsBusy
    % if isempty(IsBusy)
    %     IsBusy = false;
    % end
    % 
    % if IsBusy
    %     return;
    % end
    % 
    % IsBusy = true;

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'Merging CWT inputs...', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            global Parameter1
            global XI
            global YI
            global XI_G
            global YI_G
            global PCA_Features_Matrix
            global kICA_Features_Matrix
            global nICA_Features_Matrix
            
            global Feature
            global cwtmor
            global cwtout
            global cwtout_abs
            global WL
            global WL2
            global WLFs
            global Fe
            
            clear Feature
            clear cwtmor
            clear cwtout
            clear cwtout_abs
            clear WL
            clear WL2
            clear WLFs
            clear Fe
            

            Parameter1 = str2num(get(handles.Parameter1,'String'));
            N_Angles = str2num(get(handles.N_Angles,'String'));
            N_Scales = str2num(get(handles.N_Scales,'String'));
            
            
            value2 = get(handles.checkbox11, 'Value');


            valu_auto = get(handles.checkbox35, 'Value');
            if valu_auto == 1
                if N_Scales > 5
                    N_Scales = 5;
                end
            end
            set(handles.N_Scales, 'String', N_Scales); % Onto GUI
            
            
            Input1 = XI(:)- XI(:); Input2 = XI(:)- XI(:); Input3 = XI(:)- XI(:); Input4 = XI(:)- XI(:); Input5 = XI(:)- XI(:);


            if value2 == 1
                for ImNumb1 = 1:Parameter1
                    fh4 = csvread(['X_Y_PData' num2str(ImNumb1) '.csv']);
                    fh5 = fh4(:,3);
                    fh6(:,ImNumb1) = fh5;
                    Input2 = fh6;
                end
            end


            clear CWT_Inputs
            CWT_Inputs = [Input1 Input2 Input3 Input4 Input5];
            
            XY_CWT_Inputs = [XI(:) YI(:) CWT_Inputs];
            XY_CWT_Inputs(any(isnan(XY_CWT_Inputs), 2), :) = []; % Removing the NaNs
            XY_CWT_Inputs(:,all(XY_CWT_Inputs == 0))=[];         % Removing the zeros columns
            CWT_Inputs_Size = size(XY_CWT_Inputs);
            CWT_Inputs_Size = CWT_Inputs_Size(1,2);
            
            global XI
            global YI
            sigma1 = str2num(get(handles.edit73,'String'));
            clearvars -global CWT_Input_Features_Matrix
            global CWT_Input_Features_Matrix
            
            for ii = 3 : CWT_Inputs_Size
                x = XY_CWT_Inputs(:,1); y = XY_CWT_Inputs(:,2); D1 = XY_CWT_Inputs(:,ii);
                ImD4 = griddata(x,y,D1,XI,YI,'nearest');
                   ImInputtr0  = FilterB(ImD4,sigma1);
            
            CWT_Input_Features_Matrix(:,:,ii-2) = ImInputtr0;
            
            end
            
            CWT_Input_Features_Matrix_Size1 = size(CWT_Input_Features_Matrix);
            CWT_Input_Features_Matrix_Size2 = size(CWT_Input_Features_Matrix_Size1); CWT_Input_Features_Matrix_Size2 = CWT_Input_Features_Matrix_Size2(1,2);
            
                        if CWT_Input_Features_Matrix_Size2 == 2
            
                            CWT_Input_Features_Matrix_Size = 1;
                            WCT_F = CWT_Input_Features_Matrix_Size * N_Angles * N_Scales; % Number of input/output CWT features
            
                        elseif CWT_Input_Features_Matrix_Size2 == 3
            
                            CWT_Input_Features_Matrix_Size3 = size(CWT_Input_Features_Matrix);
                            CWT_Input_Features_Matrix_Size4 = CWT_Input_Features_Matrix_Size3(1,3);
                            WCT_F = CWT_Input_Features_Matrix_Size4 * N_Angles * N_Scales; % Number of input/output CWT features
            
                        end
            
                    set(handles.N_CWT, 'String', WCT_F); % Onto GUI
                    set(handles.D_Reduct, 'String', WCT_F); % Onto GUI
            
            scale_dilation = str2num(get(handles.Scale_D,'String'));
            Scales =  1:scale_dilation:(N_Scales*scale_dilation);
            set(handles.Scales, 'String', regexprep(num2str(Scales),'\s+',',')) % Onto GUI
            global betad
            
            orderx = str2num(get(handles.edit92,'String'));
            ordery = str2num(get(handles.edit93,'String'));
            
            if orderx == ordery
                betad = 90;
            else
                betad = 180;
            end
            
            set(handles.edit100, 'String', betad); % Onto GUI


            betad = str2num(get(handles.edit100,'String'));
            Angless = betad/(N_Angles) - betad/(N_Angles)  :betad/(N_Angles) : betad - betad/(N_Angles);
            set(handles.Angles, 'String', regexprep(num2str(Angless),'\s+',',')) % Onto GUI
            
            
            % CWT_Input_Features_Matrix_Size = size(CWT_Input_Features_Matrix)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            close(h)
            pause(0.01);

    % % Reset the IsBusy state
    % IsBusy = false;

        end

        % Button pushed function: pushbutton46
        function pushbutton46_Callback(app, event)

    % persistent IsBusy
    % if isempty(IsBusy)
    %     IsBusy = false;
    % end
    % 
    % if IsBusy
    %     return;
    % end
    % 
    % IsBusy = true;

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h = waitbar(0.5,'AutoLine: A Lineaments Detection Algorithm with Hyper Parameter Optimization', 'WindowStyle', 'modal');
            frames = java.awt.Frame.getFrames();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            global Parameter1
            global XI
            global YI
            global XI_G
            global YI_G
            global PCA_Features_Matrix
            global kICA_Features_Matrix
            global nICA_Features_Matrix
            global  CWT_Features_Matrix
            global  Spectral_PCA_Features_Matrix
            global  Spectral_kICA_Features_Matrix
            global  Spectral_nICA_Features_Matrix
            global All_Sat_Matrix
            global All_Data_Matrix
            global X_Pix
            global Y_Pix
            global curv
            global CWT_Input_Features_Matrix
            
            % CWT_Input_Features_Matrix_Size = size(CWT_Input_Features_Matrix)
            
            
            global sigma2
            curv = str2num(get(handles.edit98,'String'));
            X_Pix = str2num(get(handles.X_Pix,'String'));
            Y_Pix = str2num(get(handles.Y_Pix,'String'));
            sigma2 = str2num(get(handles.Sigma2,'String'));
            
            global N_CWT
            N_CWT = str2num(get(handles.N_CWT,'String'));
            
            % Define the range of the hyperparameters: First Run
            N_CWT = str2num(get(handles.N_CWT,'String'));
            
            N_Scales = str2num(get(handles.N_Scales,'String'));
            SDF = str2num(get(handles.Scale_D,'String'));
            WSFR = str2num(get(handles.Sigma3,'String'));
            N_Angles = str2num(get(handles.N_Angles,'String'));
            m = str2num(get(handles.edit92,'String'));
            n = str2num(get(handles.edit93,'String'));
            DR = str2num(get(handles.D_Reduct,'String'));
            Line_Res = str2num(get(handles.edit99,'String'));
            w = str2num(get(handles.edit91,'String'));
            NSFA  = str2num(get(handles.edit101,'String'));
            VSFW = str2num(get(handles.edit98,'String'));
            valu_auto = get(handles.checkbox35, 'Value');
            
            % fbeta_score = Lineaments_Auto(CWT_Input_Features_Matrix, N_Scales, SDF, WSFR, N_Angles, m, n, DR, Line_Res, w, NSFA, VSFW)
            
            
            % N_CWT = 360
            % DR = 120
            n = 7;
            B1 = DR;
            
                p1 = round(0.2*(B1^0.7)); % Linearly increase p with B, then round to the nearest integer
                if p1 == 0
                    p1 = 1; % Ensure p is at least 1
                end
                A = (B1 - (p1 * n)):p1:(B1 + (p1 * n));
                A = A(A > 1); % Ensure all elements are greater than 1
                C = sum(A < B1);
                A = A(1:(2*C)+1);
                A = A(A < N_CWT);
                categories1 = cellstr(num2str(A'))';
            
            
            n = 6;
            
            if w == 2 || w == 1
                B = 3;
            else
                B = w;
            end
            
                p2 = round(0.2*(B^0.7)); % Linearly increase p with B, then round to the nearest integer
                if p2 == 0
                    p2 = 1; % Ensure p is at least 1
                end
                A = (B - (p2 * n)):p2:(B + (p2 * n));
                A = A(A > 1); % Ensure all elements are greater than 1
                C = sum(A < B);
                A = A(1:(2*C)+1);
                categories2 = cellstr(num2str(A'))';


            vars = [
                optimizableVariable('N_Scales', {'2', '3', '4', '5'}, 'Type', 'categorical')
            %     optimizableVariable('SDF', {'1', '2', '3'}, 'Type', 'categorical')
                optimizableVariable('WSFR', {'0','0.01', '0.02', '0.03', '0.04', '0.05', '0.06', '0.07', '0.08', '0.09', '0.10', '0.12', '0.14', '0.16', '0.18', '0.20', '0.25', '0.30'}, 'Type', 'categorical')
            %     optimizableVariable('N_Angles', {'1', '2', '3', '4', '5', '6', '7', '8'}, 'Type', 'categorical')
            %     optimizableVariable('m', {'0', '1', '2', '3'}, 'Type', 'categorical')
            %     optimizableVariable('n', {'0', '1', '2', '3'}, 'Type', 'categorical')
                optimizableVariable('DR', categories1, 'Type', 'categorical')
            %     optimizableVariable('Line_Res', {'400', '500', '600'}, 'Type', 'categorical')
                optimizableVariable('w', categories2, 'Type', 'categorical');
            %     optimizableVariable('NSFA', {'12','13','14','15','16', '17', '18', '19', '20', '21', '22', '23', '24'}, 'Type', 'categorical')
                optimizableVariable('VSFW', {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7'}, 'Type', 'categorical')
            ];


            % Define the function to optimize
            fun = @(x) 1 - Lineaments_Auto(CWT_Input_Features_Matrix, str2double(string(x.N_Scales)), SDF, str2double(string(x.WSFR)), ...
                N_Angles, m, n, str2double(string(x.DR)), Line_Res, str2double(string(x.w)), NSFA, str2double(string(x.VSFW)), valu_auto);
            
            
            BHO_Iter = str2num(get(handles.edit102,'String'));
            LastIters = 0.80;
            % ceil(LastIters*BHO_Iter)
            
            % Perform the optimization
            results = bayesopt(fun, vars, 'MaxObjectiveEvaluations', BHO_Iter);
            
            results
            
            % Extract the optimal hyperparameters
            bestHyperparameters = results.XAtMinEstimatedObjective;
            
            % Write the results table to an Excel file
            writetable(bestHyperparameters, 'bayesopt_results.xlsx');
            
            % Extract the variables at each iteration
            XTraceTable = results.XTrace;


            % Get the number of variables
            numVars = width(XTraceTable);
            
            % Create a new figure
            figure;
            
            % Plot each variable
            for i = 1:numVars
                % Create a new subplot for this variable
                subplot(numVars, 1, i);


            cat_data0 = XTraceTable{:,i};
            str_data0 = cellstr(cat_data0);
            numeric_data0 = str2double(str_data0);
            % numeric_data00 = zeros(BHO_Iter,numVars);
            % numeric_data00(:,i) = str2double(str_data0');
            
            % If XTraceTable contains a categorical array with numeric categories
            cat_data = XTraceTable{ceil(LastIters*BHO_Iter):end, i};
            str_data = cellstr(cat_data);
            numeric_data = str2double(str_data);
            % numeric_data_2 = (numeric_data - mean(numeric_data))/std(numeric_data);
            CV2 = std(numeric_data) / mean(numeric_data);     % Coefficient of Variation:
            
            
            Var1(1,i) = CV2;
            Var2 = round(100*CV2);
            
            Mean_Val = median(numeric_data);
            Min_Val = min(numeric_data);
            Max_Val = max(numeric_data);
            lower_value = prctile(numeric_data, 15);
            upper_value = prctile(numeric_data, 85);
            
            
            if i == 1
                val1 = round(Mean_Val);
                set(handles.N_Scales, 'String', val1);
            
            elseif i == 2
                val2 = Mean_Val;
                set(handles.Sigma3, 'String', val2);
            
            elseif i == 3
                val3 = round(Mean_Val);
                set(handles.D_Reduct, 'String', val3);
            
            elseif i == 4
                val4 = round(Mean_Val);
                set(handles.edit91, 'String', val4);
            
            elseif i == 5
                 val5 = Mean_Val;
                set(handles.edit98, 'String', val5);
            
            
            end
            
            
            v1 = str2num(get(handles.N_Scales,'String'));
            v2 = str2num(get(handles.Sigma3,'String'));
            v3 = str2num(get(handles.D_Reduct,'String'));
            v4 = str2num(get(handles.edit91,'String'));
            v5 = str2num(get(handles.edit98,'String'));
            
            
            CWT_Input_Features_Matrix_Size1 = size(CWT_Input_Features_Matrix);
            CWT_Input_Features_Matrix_Size2 = size(CWT_Input_Features_Matrix_Size1); CWT_Input_Features_Matrix_Size2 = CWT_Input_Features_Matrix_Size2(1,2);
            
                        if CWT_Input_Features_Matrix_Size2 == 2
            
                            CWT_Input_Features_Matrix_Size = 1;
                            WCT_F_2 = CWT_Input_Features_Matrix_Size * N_Angles * v1; % Number of input/output CWT features
            
                        elseif CWT_Input_Features_Matrix_Size2 == 3
            
                            CWT_Input_Features_Matrix_Size3 = size(CWT_Input_Features_Matrix);
                            CWT_Input_Features_Matrix_Size4 = CWT_Input_Features_Matrix_Size3(1,3);
                            WCT_F_2 = CWT_Input_Features_Matrix_Size4 * N_Angles * v1; % Number of input/output CWT features
            
                        end
            
            set(handles.N_CWT, 'String', WCT_F_2); % Onto GUI
            
                    if v3 > WCT_F_2
                        set(handles.D_Reduct, 'String', WCT_F_2);
                    end
            
            
            vals = [v1 v2 v3 v4 v5];


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                % Plot the value of this variable at each iteration
                plot(numeric_data0, 'k');
                 hold on;
                 H_Line = vals(1,i);
                 yline(H_Line, '--', 'Color', [0.85 0.33 0.1]);
            
                 iterations = 1:1:BHO_Iter;
            
            %      numeric_data = str2double(str_data);
            %      cat_data = XTraceTable{ceil(LastIters*BHO_Iter):end, i};
            szd = size(numeric_data);
            
            x_fill = [iterations(ceil(LastIters*BHO_Iter):end), fliplr(iterations(ceil(LastIters*BHO_Iter):end))];
            y_fill = [lower_value*ones(szd(1,2), szd(1,1)), upper_value*ones(szd(1,2), szd(1,1))];
            fill(x_fill, y_fill, 'r', 'FaceAlpha', 0.3);


            %      title(['Min = ', num2str(Min_Val), ', Max = ', num2str(Max_Val)]);
                 title(['Min = ', num2str(Min_Val), ', Max = ', num2str(Max_Val), ', Variation = ', num2str(Var2)]);
                 subtitle(['L 15 % = ', num2str(lower_value), ', U 85 % = ', num2str(upper_value), ', Med = ', num2str(Mean_Val), ', Last = ', num2str(numeric_data0(end))]);
            
                % Label the x-axis
                xlabel('Iteration');
            
                % Label the y-axis with the variable name
                ylabel(XTraceTable.Properties.VariableNames{i});
            
            
            end


            % Extract the minimum observed objective function value at each iteration
            ObjectiveMinimumTrace = results.ObjectiveMinimumTrace;
            EstimatedObjectiveMinimumTrace = results.EstimatedObjectiveMinimumTrace;
            
            % Create a new figure
            figure;
            
            % Plot the minimum objective function value at each iteration
            plot(EstimatedObjectiveMinimumTrace, 'k', 'LineWidth', 0.25);
            
            hold on;
            
            plot(ObjectiveMinimumTrace, 'o', 'MarkerSize', 2, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'r');
            
            legend('Estimated', 'Observed', 'Location', 'best');
            
            hold off;
            
            
            % Label the x-axis
            xlabel('Iteration');
            
            % Label the y-axis
            ylabel('Min Objective');
            
            % Title for the plot
            title('Performance of Lineament Extraction based on BHO');
            
            % Adjust the figure layout
            grid off;


            close(h)
            pause(0.1);

    % % Reset the IsBusy state
    % IsBusy = false;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ELC2D (ver. 1.2) par Dr. B.Abbassi (bahman.abbassi@uqat.ca)';
            app.figure1.Tag = 'figure1';
            app.uipanel19_2 = uipanel(app.figure1);
            app.uipanel19_2.Title = 'Extraction de linéaments';
            app.uipanel19_2.Tag = 'uipanel19';
            app.text119 = uilabel(app.uipanel19_2);
            app.text119.Tag = 'text119';
            app.text119.Text = 'w';
            app.edit91 = uieditfield(app.uipanel19_2, 'text');
            app.edit91.Tag = 'edit91';
            app.edit91.Value = '76';
            app.Lineaments = uibutton(app.uipanel19_2, 'push');
            app.Lineaments.ButtonPushedFcn = createCallbackFcn(app, @Lineaments_Callback, true);
            app.Lineaments.Tag = 'Lineaments';
            app.Lineaments.Text = 'Linéaments ';
            app.text127 = uilabel(app.uipanel19_2);
            app.text127.Tag = 'text127';
            app.text127.Text = 'VLFÉ';
            app.edit98 = uieditfield(app.uipanel19_2, 'text');
            app.edit98.Tag = 'edit98';
            app.edit98.Value = '0.25';
            app.text128 = uilabel(app.uipanel19_2);
            app.text128.Tag = 'text128';
            app.text128.Text = 'Résolution des lignes';
            app.edit99 = uieditfield(app.uipanel19_2, 'text');
            app.edit99.Tag = 'edit99';
            app.edit99.Value = '760';
            app.text130 = uilabel(app.uipanel19_2);
            app.text130.Tag = 'text130';
            app.text130.Text = 'FÉ # d’angles';
            app.edit101 = uieditfield(app.uipanel19_2, 'text');
            app.edit101.Tag = 'edit101';
            app.edit101.Value = '18';
            app.pushbutton46 = uibutton(app.uipanel19_2, 'push');
            app.pushbutton46.ButtonPushedFcn = createCallbackFcn(app, @pushbutton46_Callback, true);
            app.pushbutton46.Tag = 'pushbutton46';
            app.pushbutton46.Text = 'AutoLigne';
            app.text131 = uilabel(app.uipanel19_2);
            app.text131.Tag = 'text131';
            app.text131.Text = 'MaxIter Opt Bayésienne';
            app.edit102 = uieditfield(app.uipanel19_2, 'text');
            app.edit102.Tag = 'edit102';
            app.edit102.Value = '15';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Données de points d''entrée';
            app.uipanel5.Tag = 'uipanel5';
            app.text76 = uilabel(app.uipanel5);
            app.text76.Tag = 'text76';
            app.text76.Text = 'x';
            app.text78 = uilabel(app.uipanel5);
            app.text78.Tag = 'text78';
            app.text78.Text = 'Xn';
            app.text79 = uilabel(app.uipanel5);
            app.text79.Tag = 'text79';
            app.text79.Text = 'Yn';
            app.PointData = uidropdown(app.uipanel5);
            app.PointData.Items = {'Interpolation 2D', 'Regular', 'Scattered'};
            app.PointData.ValueChangedFcn = createCallbackFcn(app, @PointData_Callback, true);
            app.PointData.Tag = 'PointData';
            app.PointData.Value = 'Interpolation 2D';
            app.Y_Pix = uibutton(app.uipanel5, 'push');
            app.Y_Pix.Tag = 'Y_Pix';
            app.Y_Pix.Text = '';
            app.X_Pix = uibutton(app.uipanel5, 'push');
            app.X_Pix.Tag = 'X_Pix';
            app.X_Pix.Text = '';
            app.Parameter1 = uieditfield(app.uipanel5, 'text');
            app.Parameter1.Tag = 'Parameter1';
            app.Parameter1.Value = '1';
            app.text39 = uilabel(app.uipanel5);
            app.text39.Tag = 'text39';
            app.text39.Text = '#';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Espacement';
            app.uipanel2.Tag = 'uipanel2';
            app.XSpacing = uieditfield(app.uipanel2, 'text');
            app.XSpacing.Tag = 'XSpacing';
            app.Sigma1 = uieditfield(app.uipanel2, 'text');
            app.Sigma1.Tag = 'Sigma1';
            app.Sigma1.Value = '0';
            app.text19 = uilabel(app.uipanel2);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Espacement';
            app.text24 = uilabel(app.uipanel2);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Filtre';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Coordonnées';
            app.uipanel1.Tag = 'uipanel1';
            app.CoordsReads = uidropdown(app.uipanel1);
            app.CoordsReads.Items = {'Méthode', 'Rectangular coordinates'};
            app.CoordsReads.ValueChangedFcn = createCallbackFcn(app, @CoordsReads_Callback, true);
            app.CoordsReads.Tag = 'CoordsReads';
            app.CoordsReads.Value = 'Méthode';
            app.text41 = uilabel(app.figure1);
            app.text41.Tag = 'text41';
            app.text41.Text = 'Min Lon';
            app.text42 = uilabel(app.figure1);
            app.text42.Tag = 'text42';
            app.text42.Text = 'Max Lon';
            app.text43 = uilabel(app.figure1);
            app.text43.Tag = 'text43';
            app.text43.Text = 'Min Lat';
            app.text44 = uilabel(app.figure1);
            app.text44.Tag = 'text44';
            app.text44.Text = 'Max Lat';
            app.Xmin = uieditfield(app.figure1, 'text');
            app.Xmin.Tag = 'Xmin';
            app.Xmax = uieditfield(app.figure1, 'text');
            app.Xmax.Tag = 'Xmax';
            app.Ymin = uieditfield(app.figure1, 'text');
            app.Ymin.Tag = 'Ymin';
            app.Ymax = uieditfield(app.figure1, 'text');
            app.Ymax.Tag = 'Ymax';
            app.uipanel10 = uipanel(app.figure1);
            app.uipanel10.Title = 'Tracer';
            app.uipanel10.Tag = 'uipanel10';
            app.popupmenu5 = uidropdown(app.uipanel10);
            app.popupmenu5.Items = {'Tracer les résultats', 'Données ponctuelles', 'TOC', 'TOC (Inverser les couleurs)', 'ACPS', 'ACPS (Inverser les couleurs)', 'Cibles', 'Score F-beta'};
            app.popupmenu5.ValueChangedFcn = createCallbackFcn(app, @popupmenu5_Callback, true);
            app.popupmenu5.Tag = 'popupmenu5';
            app.popupmenu5.Value = 'Tracer les résultats';
            app.Sigma2 = uieditfield(app.uipanel10, 'text');
            app.Sigma2.Tag = 'Sigma2';
            app.Sigma2.Value = '0';
            app.text25 = uilabel(app.uipanel10);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Filtre ';
            app.uipanel14 = uipanel(app.figure1);
            app.uipanel14.Title = 'Linéaments numérisés';
            app.uipanel14.Tag = 'uipanel14';
            app.popupmenu13 = uidropdown(app.uipanel14);
            app.popupmenu13.Items = {'Type de cibles', 'Point Data', 'Image'};
            app.popupmenu13.ValueChangedFcn = createCallbackFcn(app, @popupmenu13_Callback, true);
            app.popupmenu13.Tag = 'popupmenu13';
            app.popupmenu13.Value = 'Type de cibles';
            app.edit72 = uieditfield(app.uipanel14, 'text');
            app.edit72.Tag = 'edit72';
            app.edit73 = uieditfield(app.uipanel14, 'text');
            app.edit73.Tag = 'edit73';
            app.edit73.Value = '1';
            app.text98 = uilabel(app.uipanel14);
            app.text98.Tag = 'text98';
            app.text98.Text = 'Espacement';
            app.text99 = uilabel(app.uipanel14);
            app.text99.Tag = 'text99';
            app.text99.Text = 'Filtre';
            app.text100 = uilabel(app.uipanel14);
            app.text100.Tag = 'text100';
            app.text100.Text = 'x';
            app.text101 = uilabel(app.uipanel14);
            app.text101.Tag = 'text101';
            app.text101.Text = 'Xn';
            app.text102 = uilabel(app.uipanel14);
            app.text102.Tag = 'text102';
            app.text102.Text = 'Yn';
            app.edit89 = uieditfield(app.uipanel14, 'text');
            app.edit89.Tag = 'edit89';
            app.text117 = uilabel(app.uipanel14);
            app.text117.Tag = 'text117';
            app.text117.Text = 'Seuil 1';
            app.edit103 = uieditfield(app.uipanel14, 'text');
            app.edit103.Tag = 'edit103';
            app.text132 = uilabel(app.uipanel14);
            app.text132.Tag = 'text132';
            app.text132.Text = 'Seuil 2';
            app.pushbutton37 = uibutton(app.uipanel14, 'push');
            app.pushbutton37.Tag = 'pushbutton37';
            app.pushbutton37.Text = '';
            app.pushbutton38 = uibutton(app.uipanel14, 'push');
            app.pushbutton38.Tag = 'pushbutton38';
            app.pushbutton38.Text = '';
            app.uipanel11 = uipanel(app.figure1);
            app.uipanel11.Title = 'Extraction de caractéristiques spectrales';
            app.uipanel11.Tag = 'uipanel11';
            app.N_Scales = uieditfield(app.uipanel11, 'text');
            app.N_Scales.Tag = 'N_Scales';
            app.text62 = uilabel(app.uipanel11);
            app.text62.Tag = 'text62';
            app.text62.Text = '# d’échelles (na)';
            app.Scale_D = uieditfield(app.uipanel11, 'text');
            app.Scale_D.Tag = 'Scale_D';
            app.text63 = uilabel(app.uipanel11);
            app.text63.Tag = 'text63';
            app.text63.Text = 'Dilatation des échelle';
            app.N_Angles = uieditfield(app.uipanel11, 'text');
            app.N_Angles.Tag = 'N_Angles';
            app.text64 = uilabel(app.uipanel11);
            app.text64.Tag = 'text64';
            app.text64.Tooltip = 'N_Angles';
            app.text64.Text = '# d’angles TOC';
            app.Angles = uieditfield(app.uipanel11, 'text');
            app.Angles.Tag = 'Angles';
            app.text65 = uilabel(app.uipanel11);
            app.text65.Tag = 'text65';
            app.text65.Text = 'Angles TOC';
            app.Scales = uieditfield(app.uipanel11, 'text');
            app.Scales.Tag = 'Scales';
            app.text66 = uilabel(app.uipanel11);
            app.text66.Tag = 'text66';
            app.text66.Text = 'Échelles(a)';
            app.CWT = uibutton(app.uipanel11, 'push');
            app.CWT.ButtonPushedFcn = createCallbackFcn(app, @CWT_Callback, true);
            app.CWT.Tag = 'CWT';
            app.CWT.Text = 'TOC';
            app.S_PCA = uibutton(app.uipanel11, 'push');
            app.S_PCA.ButtonPushedFcn = createCallbackFcn(app, @S_PCA_Callback, true);
            app.S_PCA.Tag = 'S_PCA';
            app.S_PCA.Text = 'ACPS';
            app.Status2 = uibutton(app.uipanel11, 'push');
            app.Status2.Tag = 'Status2';
            app.Status2.Text = '';
            app.Sigma3 = uieditfield(app.uipanel11, 'text');
            app.Sigma3.Tag = 'Sigma3';
            app.text80 = uilabel(app.uipanel11);
            app.text80.Tag = 'text80';
            app.text80.Text = 'RLCO';
            app.uipanel20 = uipanel(app.uipanel11);
            app.uipanel20.Title = 'Entrées TOC';
            app.uipanel20.Tag = 'uipanel20';
            app.checkbox11 = uicheckbox(app.uipanel20);
            app.checkbox11.Tag = 'checkbox11';
            app.checkbox11.Text = 'Données ponctuelles';
            app.checkbox11.Value = true;
            app.pushbutton44 = uibutton(app.uipanel20, 'push');
            app.pushbutton44.ButtonPushedFcn = createCallbackFcn(app, @pushbutton44_Callback, true);
            app.pushbutton44.Tag = 'pushbutton44';
            app.pushbutton44.Text = 'Fusionner';
            app.N_CWT = uieditfield(app.uipanel11, 'text');
            app.N_CWT.Tag = 'N_CWT';
            app.text71 = uilabel(app.uipanel11);
            app.text71.Tag = 'text71';
            app.text71.Text = '# Caract. TOC';
            app.text72 = uilabel(app.uipanel11);
            app.text72.Tag = 'text72';
            app.text72.Text = 'RD à';
            app.D_Reduct = uieditfield(app.uipanel11, 'text');
            app.D_Reduct.Tag = 'D_Reduct';
            app.edit92 = uieditfield(app.uipanel11, 'text');
            app.edit92.Tag = 'edit92';
            app.edit92.Value = '1';
            app.text121 = uilabel(app.uipanel11);
            app.text121.Tag = 'text121';
            app.text121.Text = 'OrdreX (n)';
            app.text122 = uilabel(app.uipanel11);
            app.text122.Tag = 'text122';
            app.text122.Text = 'OrdreY (m)';
            app.edit93 = uieditfield(app.uipanel11, 'text');
            app.edit93.Tag = 'edit93';
            app.edit93.Value = '0';
            app.edit100 = uieditfield(app.uipanel11, 'text');
            app.edit100.Tag = 'edit100';
            app.edit100.Value = '180';
            app.text129 = uilabel(app.uipanel11);
            app.text129.Tag = 'text129';
            app.text129.Text = 'β';
            app.checkbox35 = uicheckbox(app.uipanel11);
            app.checkbox35.Tag = 'checkbox35';
            app.checkbox35.Text = {'Modifier les ordres'; 'par échelles'};
            app.uipanel19 = uipanel(app.figure1);
            app.uipanel19.Title = 'Sélectionner des caractéristiques';
            app.uipanel19.Tag = 'uipanel19';
            app.checkbox7 = uicheckbox(app.uipanel19);
            app.checkbox7.Tag = 'checkbox7';
            app.checkbox7.Text = 'ACPS';
            app.checkbox6 = uicheckbox(app.uipanel19);
            app.checkbox6.Tag = 'checkbox6';
            app.checkbox6.Text = 'TOC';
            app.checkbox2 = uicheckbox(app.uipanel19);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Text = 'Données ponctuelles';
            app.ClearAll = uibutton(app.figure1, 'push');
            app.ClearAll.ButtonPushedFcn = createCallbackFcn(app, @ClearAll_Callback, true);
            app.ClearAll.Tag = 'ClearAll';
            app.ClearAll.Text = 'Effacer tout';
            app.CloseAll = uibutton(app.figure1, 'push');
            app.CloseAll.ButtonPushedFcn = createCallbackFcn(app, @CloseAll_Callback, true);
            app.CloseAll.Tag = 'CloseAll';
            app.CloseAll.Text = 'Fermer tout';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ELC2D(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)CLE2D_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end